classdef ConvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        x1Label     matlab.ui.control.Label
        x1DropDown  matlab.ui.control.DropDown
        x2Label     matlab.ui.control.Label
        x2DropDown  matlab.ui.control.DropDown
        x1Axes      matlab.ui.control.UIAxes
        x2Axes      matlab.ui.control.UIAxes
        SlideAxes   matlab.ui.control.UIAxes
        ConvAxes    matlab.ui.control.UIAxes
        RunButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        sampleInt
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sampleInt = 0.1;
            
            SignalSubplot(1, app.x1DropDown, app.x1Axes);
            SignalSubplot(2, app.x2DropDown, app.x2Axes);
        end

        % Value changed function: x1DropDown
        function x1DropDownValueChanged(app, event)
            SignalSubplot(1, app.x1DropDown, app.x1Axes);
        end

        % Value changed function: x2DropDown
        function x2DropDownValueChanged(app, event)
            SignalSubplot(2, app.x2DropDown, app.x2Axes);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            run Anim.m;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1:';
            app.x1DropDown = uidropdown(app.UIFigure);
            app.x1DropDown.Items = {'rect', 'x=t', 'sin', 'cos'};
            app.x1DropDown.ValueChangedFcn = createCallbackFcn(app, @x1DropDownValueChanged, true);
            app.x1DropDown.Value = 'rect';
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x2:';
            app.x2DropDown = uidropdown(app.UIFigure);
            app.x2DropDown.Items = {'rect', 'x=t', 'sin', 'cos'};
            app.x2DropDown.ValueChangedFcn = createCallbackFcn(app, @x2DropDownValueChanged, true);
            app.x2DropDown.Value = 'rect';
            app.x1Axes = uiaxes(app.UIFigure);
            title(app.x1Axes, '1st Signal')
            xlabel(app.x1Axes, 't')
            ylabel(app.x1Axes, 'x1')
            app.x2Axes = uiaxes(app.UIFigure);
            title(app.x2Axes, '2nd Signal')
            xlabel(app.x2Axes, 't')
            ylabel(app.x2Axes, 'x2')
            app.SlideAxes = uiaxes(app.UIFigure);
            title(app.SlideAxes, '...Sliding...')
            xlabel(app.SlideAxes, 't')
            ylabel(app.SlideAxes, 'x1 | x2')
            app.ConvAxes = uiaxes(app.UIFigure);
            title(app.ConvAxes, 'Convolution')
            xlabel(app.ConvAxes, 't')
            ylabel(app.ConvAxes, 'x1 * x2')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ConvApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end